classdef Photocurrent_Acquire_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TestParametersPanel            matlab.ui.container.Panel
        umLabel_2                      matlab.ui.control.Label
        umLabel                        matlab.ui.control.Label
        inLabel                        matlab.ui.control.Label
        cmLabel                        matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        ApertureEditField              matlab.ui.control.NumericEditField
        ApertureEditFieldLabel         matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        TempEditField                  matlab.ui.control.NumericEditField
        TempEditFieldLabel             matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        PhotocurrentAcqusitionLabel    matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        TimeTab                        matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        VoltageTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        AcqusitionPanel                matlab.ui.container.Panel
        TimeSweepButton                matlab.ui.control.StateButton
        SaveDataButton                 matlab.ui.control.Button
        VoltageSweepButton             matlab.ui.control.Button
        SweepParametersPanel           matlab.ui.container.Panel
        SettlingTimeEditField          matlab.ui.control.NumericEditField
        SettlingTimeEditFieldLabel     matlab.ui.control.Label
        AveragesEditField              matlab.ui.control.NumericEditField
        AveragesEditFieldLabel         matlab.ui.control.Label
        StepVoltageEditField           matlab.ui.control.NumericEditField
        StepVoltageEditFieldLabel      matlab.ui.control.Label
        EndVoltageEditField            matlab.ui.control.NumericEditField
        EndVoltageEditFieldLabel       matlab.ui.control.Label
        StartVoltageEditField          matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel     matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        VLabel_4                       matlab.ui.control.Label
        VLabel_3                       matlab.ui.control.Label
        VLabel_2                       matlab.ui.control.Label
        LockinAmplifierPanel           matlab.ui.container.Panel
        HzLabel                        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        AlwayscheckforinputoutputoverloadsLabel_2  matlab.ui.control.Label
        TimeConstantDropDown           matlab.ui.control.DropDown
        TimeConstantDropDownLabel      matlab.ui.control.Label
        SR830SensitivityDropDown       matlab.ui.control.DropDown
        SR830SensitivityDropDownLabel  matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        VLabel_5                       matlab.ui.control.Label
        SetLockInButton                matlab.ui.control.Button
        CurrentPreamplifierPanel       matlab.ui.container.Panel
        AlwayscheckforinputoutputoverloadsLabel  matlab.ui.control.Label
        BiasOutputSwitch               matlab.ui.control.Switch
        BiasOutputSwitchLabel          matlab.ui.control.Label
        ConstantVoltageEditField       matlab.ui.control.NumericEditField
        ConstantVoltageEditFieldLabel  matlab.ui.control.Label
        SensitivityDropDown            matlab.ui.control.DropDown
        SensitivityDropDownLabel       matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        VALabel                        matlab.ui.control.Label
        SetPreampButton                matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % any variables you want to pass between functions in the AppDesigner need to be listed here.  I'll give you an example (compare lines 47 and 138)

        bias_plot;

        photocurrent_plot;

        lockin_sens_to_seta;

        lockin_tc_to_seta;

        sen_SR570a;

        photocurrent_average_plot;

        time_plot;
        
        photocurrent_all_CV_actual;

        lockin_freq;

        photocurrent_all;

        pause_time;

        averages;

temp_save;
dist_save;
aper_save;
width_save;
length_save;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetPreampButton
        function SetPreampButtonPushed(app, event)
            %when a user pushes the Set Preamp button, it sends default
            %settings and any user selected inputs to the SR570 current
            %pre-amplifier

            sen_SR570=app.SensitivityDropDown.Value;
            
            app.sen_SR570a=str2double(sen_SR570);

            SR570_sett(app.sen_SR570a);

            bias_to_set=app.ConstantVoltageEditField.Value;     %passes numbr from the Constant Voltage field to bias_to_set, which is a variable used in this code.

            bias_switch_status=app.BiasOutputSwitch.Value;
            
            %make sure a user can't set a bias leve higher than the
            %pre-amp can provide

            if bias_to_set>4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to 4.5 V');

                bias_to_set=4.5;

                app.ConstantVoltageEditField.Value=4.5;

            elseif bias_to_set<-4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to -4.5 V');

                bias_to_set=-4.5;

                app.ConstantVoltageEditField.Value=-4.5;

            end

            %now set the bias level

            if strcmpi('On',bias_switch_status) %strcmpi compares strings (text) statements for boolean true/false

                status=1;

            elseif strcmpi('Off',bias_switch_status)

                status=0;

            end

            SR570_bias(bias_to_set,status);

            disp('Current Pre-amplifier has been set');


        end

        % Button pushed function: VoltageSweepButton
        function VoltageSweepButtonPushed(app, event)
        bias_start=app.StartVoltageEditField.Value;

            bias_step=app.StepVoltageEditField.Value;

            bias_end=app.EndVoltageEditField.Value;

            current_iter=0;

            app.pause_time=app.SettlingTimeEditField.Value;

            app.averages=app.AveragesEditField.Value;

            %make sure a user can't set bias levels higher than what the
            %current pre-amp can provide

            if bias_start>4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to 4.5 V');

                bias_start=4.5;

                app.StartVoltageEditField.Value=4.5;

            elseif bias_start<-4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to -4.5 V');

                bias_start=-4.5;

                app.StartVoltageEditField.Value=-4.5;

            elseif bias_end>4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to 4.5 V');

                bias_end=4.5;

                app.EndVoltageEditField.Value=4.5;

            elseif bias_end<-4.5

                disp('WARNING.  The bias level cannnot be provided by the current pre-amplifier. Bias level has been automatically reset to -4.5 V');

                bias_end=-4.5;

                app.EndVoltageEditField.Value=-4.5;

            end

            %now start data acquisition
            
% Retrieve user-specified step voltage from the UI component
stepVoltage = app.StepVoltageEditField.Value;  % e.g., 0.1V or any value the user enters

% Define initial voltage and assume app.BiasStart and app.BiasEnd are set appropriately
voltage = app.StartVoltageEditField.Value;  
current_iter = 0;

while voltage <= app.EndVoltageEditField.Value
    current_iter = current_iter + 1;
    
    % Set the voltage using the current value
    SR570_bias(voltage, 1);     
    app.bias_plot(:, current_iter) = voltage;
    
    % Acquire measurement data
    [RMS, time, RMS_average] = SR830_lockin_acquire(app.pause_time, app.averages);
    RMS_average_all(:, current_iter) = RMS_average;
    RMS_all(:, current_iter) = RMS;
    
    % Process measurements to get photocurrent
    RMS_average_actual_all = RMS_average_all .* app.lockin_sens_to_seta;
    app.photocurrent_average_plot = RMS_average_actual_all .* app.sen_SR570a;
    RMS_actual_all = RMS_all .* app.lockin_sens_to_seta;
    app.photocurrent_all = RMS_actual_all .* app.sen_SR570a;
    
    % Update the plot
    plot(app.UIAxes, app.bias_plot, app.photocurrent_average_plot);
    
    % Increment the voltage by the user-defined step voltage
    voltage = voltage + stepVoltage;
end


            disp('Data Acquisition Complete');
        end

        % Button pushed function: SetLockInButton
        function SetLockInButtonPushed(app, event)
            

lockin_sens_to_set=app.SR830SensitivityDropDown.Value;

app.lockin_sens_to_seta=str2double(lockin_sens_to_set);

lockin_tc_to_set=app.TimeConstantDropDown.Value;

app.lockin_tc_to_seta=str2double(lockin_tc_to_set); 

SR830_lockin(app.lockin_sens_to_seta,app.lockin_tc_to_seta)

SR830_lockin_sett

app.lockin_freq=str2double(SR830_lockin_get_freq);

app.FrequencyEditField.Value=app.lockin_freq;

disp('Lock-in amplifier has been set.');


        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
          %this block of code (new_dir to disp) prompts the user to select
          %a folder in which to save data
            
            new_dir=uigetdir;
            
            current_dir_save=pwd;
            
            cd(new_dir);
            
            disp('Saving data and plots.  Be patient.')

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %save individual data files to a table with column labels:

            data_table1=table(app.bias_plot',app.photocurrent_average_plot');

            data_table1.Properties.VariableNames={'Bias (V DC)','Photocurrent (A)'};

            writetable(data_table1,'Photocurrent vs Voltage.txt');

            %take a screenshot of the Photocurrent vs Voltage Plot:

            exportgraphics(app.UIAxes,'Photocurrent vs Voltage.jpg');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            data_table2=table(app.time_plot',app.photocurrent_all_CV_actual');

            data_table2.Properties.VariableNames={'Time (s)','Photocurrent (A)'};

            writetable(data_table2,'Photocurrent vs Time.txt');

            exportgraphics(app.UIAxes2,'Photocurrent vs Time.jpg');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.temp_save=app.TempEditField.Value;

            app.dist_save=app.DistanceEditField.Value;

            app.aper_save=app.ApertureEditField.Value;

            app.width_save=app.WidthEditField.Value;

            app.length_save=app.LengthEditField.Value;

            data_table3=table(app.lockin_sens_to_seta,app.lockin_tc_to_seta,app.sen_SR570a,app.lockin_freq,app.pause_time,app.averages,app.temp_save,app.dist_save,app.aper_save,app.width_save,app.length_save);
           
            data_table3.Properties.VariableNames={'Lockin Sensitivity (V)','Lockin Time Constant (s)','Current Pre-amplifier Sensitivity (V/A)','Modulation Frequency (Hz)','Settling Time (s)','Number of Averages','Blackbody Temp (C)','Source Detector Distance (cm)','Blackbody Aperture (in)','Detector Width (um)','Detector Length (um)'};

            writetable(data_table3,'Parameters.txt');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            data_table4=table(app.photocurrent_all');

            writetable(data_table4,'All Photocurrent Observations at each Bias Level.txt');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

disp('Data have been saved to directory:')
pwd
cd(current_dir_save);

        end

        % Callback function
        function TimeReadoutSwitchValueChanged(app, event)


        end

        % Value changed function: TimeSweepButton
        function TimeSweepButtonValueChanged(app, event)
         
        % Initialize arrays for storing data
        app.time_plot=zeros(1,150);  % Initialize rms_array with zeros
        app.photocurrent_all_CV_actual=zeros(1,150);  % Initialize time_array with zeros

            for mm=1:150
           
                [RMS_all_CV,time_all_CV,RMS_CV]=SR830_lockin_acquire(0,1); %set both of these to something small so we get real-time data within the 200 ms interval set in the code
                               
                RMS_all_CV_actual=RMS_all_CV.*app.lockin_sens_to_seta;

                app.photocurrent_all_CV_actual(:,mm)=RMS_all_CV_actual.*app.sen_SR570a;

                app.time_plot(:,mm)=mm.*0.4;
                
                plot(app.UIAxes2,app.time_plot,app.photocurrent_all_CV_actual);

            end

                disp('Time acquisiton complete.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentPreamplifierPanel = uipanel(app.UIFigure);
            app.CurrentPreamplifierPanel.Title = 'Current Pre-amplifier';
            app.SetPreampButton = uibutton(app.CurrentPreamplifierPanel, 'push');
            app.SetPreampButton.ButtonPushedFcn = createCallbackFcn(app, @SetPreampButtonPushed, true);
            app.SetPreampButton.Text = 'Set Pre-amp';
            app.VALabel = uilabel(app.CurrentPreamplifierPanel);
            app.VALabel.Text = 'V/A';
            app.VLabel = uilabel(app.CurrentPreamplifierPanel);
            app.VLabel.Text = 'V';
            app.SensitivityDropDownLabel = uilabel(app.CurrentPreamplifierPanel);
            app.SensitivityDropDownLabel.Text = 'Sensitivity';
            app.SensitivityDropDown = uidropdown(app.CurrentPreamplifierPanel);
            app.SensitivityDropDown.Items = {'Select', '1E-12', '2E-12', '5E-12', '10E-12', '20E-12', '50E-12', '100E-12', '200E-12', '500E-12', '1E-9', '2E-9', '5E-9', '10E-9', '20E-9', '50E-9', '100E-9', '200E-9', '500E-9', '1E-6', '2E-6', '5E-6', '10E-6', '20E-6', '50E-6', '100E-6', '200E-6', '500E-6', '1E-3'};
            app.SensitivityDropDown.Tooltip = {'Gain of the current pre-amp.  Be sure to check for overloads.'};
            app.SensitivityDropDown.Value = '5E-9';
            app.ConstantVoltageEditFieldLabel = uilabel(app.CurrentPreamplifierPanel);
            app.ConstantVoltageEditFieldLabel.Text = 'Constant Voltage';
            app.ConstantVoltageEditField = uieditfield(app.CurrentPreamplifierPanel, 'numeric');
            app.ConstantVoltageEditField.Tooltip = {'Biases your device at a constant voltage.  Mainly for determining photocurrent or noise (photocurrent vs time).'};
            app.BiasOutputSwitchLabel = uilabel(app.CurrentPreamplifierPanel);
            app.BiasOutputSwitchLabel.Text = 'Bias Output';
            app.BiasOutputSwitch = uiswitch(app.CurrentPreamplifierPanel, 'slider');
            app.BiasOutputSwitch.Tooltip = {'Turns the bias voltage on or off.  If performing a photocurrent-voltage sweep, on/off is automatically controlled.'};
            app.AlwayscheckforinputoutputoverloadsLabel = uilabel(app.CurrentPreamplifierPanel);
            app.AlwayscheckforinputoutputoverloadsLabel.Text = 'Always check for input/output overloads';
            app.LockinAmplifierPanel = uipanel(app.UIFigure);
            app.LockinAmplifierPanel.Title = 'Lock-in Amplifier';
            app.SetLockInButton = uibutton(app.LockinAmplifierPanel, 'push');
            app.SetLockInButton.ButtonPushedFcn = createCallbackFcn(app, @SetLockInButtonPushed, true);
            app.SetLockInButton.Text = 'Set Lock-In';
            app.VLabel_5 = uilabel(app.LockinAmplifierPanel);
            app.VLabel_5.Text = 'V';
            app.sLabel = uilabel(app.LockinAmplifierPanel);
            app.sLabel.Text = 's';
            app.SR830SensitivityDropDownLabel = uilabel(app.LockinAmplifierPanel);
            app.SR830SensitivityDropDownLabel.Text = 'SR830 Sensitivity';
            app.SR830SensitivityDropDown = uidropdown(app.LockinAmplifierPanel);
            app.SR830SensitivityDropDown.Items = {'2E-9', '5E-9', '10E-9', '20E-9', '50E-9', '100E-9', '200E-9', '500E-9', '1E-6', '2E-6', '5E-6', '10E-6', '20E-6', '50E-6', '100E-6', '200E-6', '500E-6', '1E-3', '2E-3', '5E-3', '10E-3', '20E-3', '50E-3', '100E-3', '200E-3', '500E-3', '1'};
            app.SR830SensitivityDropDown.Tooltip = {'Lock-in maximum RMS value in Ch. 1.  Needs to be greater than your signal or it saturates (stright line, no noise in photocurrent vs time plot).'};
            app.SR830SensitivityDropDown.Value = '100E-3';
            app.TimeConstantDropDownLabel = uilabel(app.LockinAmplifierPanel);
            app.TimeConstantDropDownLabel.Text = 'Time Constant';
            app.TimeConstantDropDown = uidropdown(app.LockinAmplifierPanel);
            app.TimeConstantDropDown.Items = {'10E-6', '30E-6', '100E-6', '300E-6', '1E-3', '3E-3', '10E-3', '30E-3', '100E-3', '300E-3', '1'};
            app.TimeConstantDropDown.Tooltip = {'This is related to the chopper frequency.  Needs to be much longer.  For example, 200 Hz chopper = 300 ms time constant.'};
            app.TimeConstantDropDown.Value = '300E-3';
            app.AlwayscheckforinputoutputoverloadsLabel_2 = uilabel(app.LockinAmplifierPanel);
            app.AlwayscheckforinputoutputoverloadsLabel_2.Text = 'Always check for input/output overloads';
            app.FrequencyEditFieldLabel = uilabel(app.LockinAmplifierPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LockinAmplifierPanel, 'numeric');
            app.HzLabel = uilabel(app.LockinAmplifierPanel);
            app.HzLabel.Text = 'Hz';
            app.SweepParametersPanel = uipanel(app.UIFigure);
            app.SweepParametersPanel.Title = 'Sweep Parameters';
            app.VLabel_2 = uilabel(app.SweepParametersPanel);
            app.VLabel_2.Text = 'V';
            app.VLabel_3 = uilabel(app.SweepParametersPanel);
            app.VLabel_3.Text = 'V';
            app.VLabel_4 = uilabel(app.SweepParametersPanel);
            app.VLabel_4.Text = 'V';
            app.sLabel_2 = uilabel(app.SweepParametersPanel);
            app.sLabel_2.Text = 's';
            app.StartVoltageEditFieldLabel = uilabel(app.SweepParametersPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.SweepParametersPanel, 'numeric');
            app.StartVoltageEditField.Tooltip = {'Bias level where you start the photocurrent-bias sweep.'};
            app.StartVoltageEditField.Value = -4.5;
            app.EndVoltageEditFieldLabel = uilabel(app.SweepParametersPanel);
            app.EndVoltageEditFieldLabel.Text = 'End Voltage';
            app.EndVoltageEditField = uieditfield(app.SweepParametersPanel, 'numeric');
            app.EndVoltageEditField.Tooltip = {'Bias level where you finish the photocurrent-bias sweep.'};
            app.EndVoltageEditField.Value = 4.5;
            app.StepVoltageEditFieldLabel = uilabel(app.SweepParametersPanel);
            app.StepVoltageEditFieldLabel.Text = 'Step Voltage';
            app.StepVoltageEditField = uieditfield(app.SweepParametersPanel, 'numeric');
            app.StepVoltageEditField.Tooltip = {'Amount by which the bias level changes during the photocurrent-bias sweep.'};
            app.StepVoltageEditField.Value = 0.1;
            app.AveragesEditFieldLabel = uilabel(app.SweepParametersPanel);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.AveragesEditField = uieditfield(app.SweepParametersPanel, 'numeric');
            app.AveragesEditField.Tooltip = {'Number of individual lock-in samples to take at each bias level.'};
            app.AveragesEditField.Value = 50;
            app.SettlingTimeEditFieldLabel = uilabel(app.SweepParametersPanel);
            app.SettlingTimeEditFieldLabel.Text = 'Settling Time';
            app.SettlingTimeEditField = uieditfield(app.SweepParametersPanel, 'numeric');
            app.SettlingTimeEditField.Tooltip = {'When you change the bias level, current transients get introduced throughout the setup.  You need to wait a few seconds for these to relax/stabilize before taking data.'};
            app.SettlingTimeEditField.Value = 5;
            app.AcqusitionPanel = uipanel(app.UIFigure);
            app.AcqusitionPanel.Title = 'Acqusition';
            app.VoltageSweepButton = uibutton(app.AcqusitionPanel, 'push');
            app.VoltageSweepButton.ButtonPushedFcn = createCallbackFcn(app, @VoltageSweepButtonPushed, true);
            app.VoltageSweepButton.Tooltip = {'Initiates Photocurrent vs Voltage.'};
            app.VoltageSweepButton.Text = 'Voltage Sweep';
            app.SaveDataButton = uibutton(app.AcqusitionPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Saves all plots and the underlying data.'};
            app.SaveDataButton.Text = 'Save Data';
            app.TimeSweepButton = uibutton(app.AcqusitionPanel, 'state');
            app.TimeSweepButton.ValueChangedFcn = createCallbackFcn(app, @TimeSweepButtonValueChanged, true);
            app.TimeSweepButton.Text = 'Time Sweep';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.UIAxes2 = uiaxes(app.TimeTab);
            title(app.UIAxes2, 'Photocurrent-Time Plot')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Photocurrent (A)')
            zlabel(app.UIAxes2, 'Z')
            app.VoltageTab = uitab(app.TabGroup);
            app.VoltageTab.Title = 'Voltage';
            app.UIAxes = uiaxes(app.VoltageTab);
            title(app.UIAxes, 'Photocurrent-Voltage Plot')
            xlabel(app.UIAxes, 'Bias (V DC)')
            ylabel(app.UIAxes, 'Photocurrent (A)')
            zlabel(app.UIAxes, 'Z')
            app.PhotocurrentAcqusitionLabel = uilabel(app.UIFigure);
            app.PhotocurrentAcqusitionLabel.Text = 'Photocurrent Acqusition';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Use the mouse to hover over fields for explanations of their functions';
            app.TestParametersPanel = uipanel(app.UIFigure);
            app.TestParametersPanel.Title = 'Test Parameters';
            app.TempEditFieldLabel = uilabel(app.TestParametersPanel);
            app.TempEditFieldLabel.Text = 'Temp';
            app.TempEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.TestParametersPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.ApertureEditFieldLabel = uilabel(app.TestParametersPanel);
            app.ApertureEditFieldLabel.Text = 'Aperture';
            app.ApertureEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.TestParametersPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.TestParametersPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.TestParametersPanel, 'numeric');
            app.CLabel = uilabel(app.TestParametersPanel);
            app.CLabel.Text = '°C';
            app.cmLabel = uilabel(app.TestParametersPanel);
            app.cmLabel.Text = 'cm';
            app.inLabel = uilabel(app.TestParametersPanel);
            app.inLabel.Text = 'in';
            app.umLabel = uilabel(app.TestParametersPanel);
            app.umLabel.Text = 'um';
            app.umLabel_2 = uilabel(app.TestParametersPanel);
            app.umLabel_2.Text = 'um';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Photocurrent_Acquire_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end